classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        CloseMenu               matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        Panel                   matlab.ui.container.Panel
        Label                   matlab.ui.control.Label
        FrameDataDropDown       matlab.ui.control.DropDown
        FrameDataDropDownLabel  matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        Image3                  matlab.ui.control.Image
        PlayButton              matlab.ui.control.Button
        FilterDropDown          matlab.ui.control.DropDown
        FilterDropDownLabel     matlab.ui.control.Label
        RangesEditField         matlab.ui.control.EditField
        RangesEditFieldLabel    matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
    end


    properties (Access = private)
        vidObj  % video 物件
        NumFrames   % 幀數
        im  % 當前幀的圖片
        im_prev % 前一幀的圖片
        timeline_im=[]  % 時間軸影像陣列
    end

    methods (Access = private)

        function  dst = filtering(app,k)
            switch k
                case '1'  % Negative
                    dst = 255 - app.im;
                case '2'  % LR Flip
                    dst = fliplr(app.im);
                case '3'  % Darker
                    dst = app.im - 64;
                case '4'  % Difference
                    dst = imabsdiff(app.im, app.im_prev);
                    app.im_prev = app.im;
                case '5'  % Cartoonize
                    dst = cartoonize(app.im, 2, 'min');
            end
        end

        function show(app)  % 幀數選擇與顯示
            x = round(app.Slider.Value);    % 對滑桿數值取整數
            if x>1
                app.im = read(app.vidObj, x);   % 讀取第x幀，存入app.im
                app.im_prev = read(app.vidObj, x-1);    % 讀取第x-1幀，存入app.im_prev
            else
                app.im_prev = app.im;   % 在第一幀的情況
            end
            app.Image.ImageSource = app.im;     % 顯示第x幀圖片
            app.Image2.ImageSource = filtering(app, app.FilterDropDown.Value);  % 顯示第x幀的過濾後圖片
            showFrame(app);     % 更新幀數與秒數
        end

        function showFrame(app) % 顯示幀數與秒數
            frame = round(app.Slider.Value);    % 讀取幀數
            time = frame / app.vidObj.FrameRate;    % 時間=幀數/FrameRate
            if app.FrameDataDropDown.Value == '1'    % 顯示目前是第幾幀
                app.FrameDataDropDownLabel.Text = num2str(frame);
            else    % 顯示是第幾秒
                app.FrameDataDropDownLabel.Text = num2str(time);
            end
        end

        function timeline(app)  % 時間軸影像陣列
            app.timeline_im = [];   % 清空矩陣
            n = str2num(app.RangesEditField.Value); % 幀數的範圍
            src = read(app.vidObj, 1);  % 讀取影片的第一幀
            src_size = size(imresize(src, 0.1));    % 讀取圖片的大小

            for x = 1:round(app.NumFrames/15):app.NumFrames % 每15幀為間隔讀取
                if ~isempty(find(n==x))
                    smallImg = imresize(read(app.vidObj, x), 0.1);  % 將這張圖片縮小高寬各10倍
                    app.timeline_im = cat(2,app.timeline_im,smallImg);  % 將該影像im堆疊到時間軸影像的第二維
                else
                    blackImg = zeros(src_size);
                    app.timeline_im = cat(2,app.timeline_im,blackImg);  % 否則用前述黑畫面取代im
                end
            end
            app.Image3.ImageSource = app.timeline_im;   % 顯示timeline
        end

        function play(app)  % 播放影片
            n = str2num(app.RangesEditField.Value); % 影片的範圍
            for x = 1:app.NumFrames
                if ~isempty(find(n==x))
                    app.Label.Visible = 'on';   % 顯現label元件
                    app.Image2.ImageSource = read(app.vidObj,x);    % 讀取第x幀
                    pause(0.02);    % 暫停0.02秒
                    app.Label.Text = num2str(x);    % 顯示當前的幀數
                else
                    continue;
                end
                if x >= max(n)  % 播放完畢
                    show(app);  % 回到過濾圖片狀態
                    app.Label.Visible = 'off';  % 不顯現label元件
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;    % 清空命令視窗
            app.Panel.Visible = 'off';  % 不顯現Panel
            app.Label.Visible = 'off';  % 不顯現Label
            app.SaveMenu.Visible = 'off';   % 不顯現存檔按鈕
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file');    % 讓使用者選擇影片檔
            if filename==0  % 如果按下cancel則返回
                return
            else
                app.Panel.Visible = 'on';   % 顯現Panel
                app.SaveMenu.Visible = 'on';    % 顯現存檔按鈕
            end
            app.vidObj = VideoReader([pathname filename]);  % 影片存入video物件
            app.im = read(app.vidObj, 1);   % 讀取第一幀
            app.Image.ImageSource = app.im; % 顯示第一幀圖片
            app.im_prev = app.im;   % 初始情況
            app.Image2.ImageSource = filtering(app, app.FilterDropDown.Value);  % 顯示過濾結果

            app.NumFrames = app.vidObj.NumberOfFrames;  % 存入影片幀數
            app.Slider.Limits = [1 app.NumFrames];  % 設定滑桿極限
            str = sprintf('1:%d',app.NumFrames);    
            app.RangesEditField.Value = str;    % 印出影片幀數範圍

            timeline(app);  % 時間軸影像陣列
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;   % 濾波選項
            app.Image2.ImageSource = filtering(app, value); % 更新濾波結果
            timeline(app);  % 時間軸影像陣列
            show(app);  % 更新顯示
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            show(app);  % 更新顯示
        end

        % Value changed function: FrameDataDropDown
        function FrameDataDropDownValueChanged(app, event)
            showFrame(app); % 更新顯示幀數與秒數
        end

        % Value changed function: RangesEditField
        function RangesEditFieldValueChanged(app, event)
            timeline(app);  % 更新timeline
            show(app);  % 更新顯示
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            play(app);  % 播放影片
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            startupFcn(app);    % 回到app初始狀態
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);    % 關閉app
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            range = str2num(app.RangesEditField.Value); % 存入幀數範圍
            [filename pathname] = uiputfile({'*.mp4'},'Save a video file'); % 選擇存檔路徑與檔名
            vidObj2 = VideoWriter([pathname filename],'MPEG-4');    % 創建第二個video物件
            open(vidObj2);  % 開啟video物件
            for x = 1:app.NumFrames
                if ~isempty(find(range==x))
                    img = read(app.vidObj, x);  % 依序讀取幀數範圍的每一幀圖片
                    writeVideo(vidObj2,img);    % 寫入影片
                end
            end
            close(vidObj2); % 關閉video物件
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {''};
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Accelerator = 'x';
            app.CloseMenu.Text = 'Close';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'e';
            app.ExitMenu.Text = 'Exit';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Image = uiimage(app.Panel);
            app.Image2 = uiimage(app.Panel);
            app.RangesEditFieldLabel = uilabel(app.Panel);
            app.RangesEditFieldLabel.Text = 'Ranges';
            app.RangesEditField = uieditfield(app.Panel, 'text');
            app.RangesEditField.ValueChangedFcn = createCallbackFcn(app, @RangesEditFieldValueChanged, true);
            app.FilterDropDownLabel = uilabel(app.Panel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.Panel);
            app.FilterDropDown.Items = {'Negative', 'LR Flip', 'Darker', 'Difference', 'Cartoonize'};
            app.FilterDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = '1';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Image3 = uiimage(app.Panel);
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FrameDataDropDownLabel = uilabel(app.Panel);
            app.FrameDataDropDownLabel.Text = 'FrameData';
            app.FrameDataDropDown = uidropdown(app.Panel);
            app.FrameDataDropDown.Items = {'Frame', 'Second'};
            app.FrameDataDropDown.ItemsData = {'1', '2'};
            app.FrameDataDropDown.ValueChangedFcn = createCallbackFcn(app, @FrameDataDropDownValueChanged, true);
            app.FrameDataDropDown.Value = '1';
            app.Label = uilabel(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end